classdef DSP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DSPApp                          matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        Title                           matlab.ui.control.Label
        OriginalImageLabel              matlab.ui.control.Label
        BlockPermutationLabel           matlab.ui.control.Label
        PixelPermutationLabel           matlab.ui.control.Label
        DecryptedImageLabel             matlab.ui.control.Label
        SelectfromPredefinedImageLabel  matlab.ui.control.Label
        SelectImage                     matlab.ui.control.DropDown
        LoadCustomImageButton           matlab.ui.control.Button
        DWTCompressedImageLabel         matlab.ui.control.Label
        RestoredImageLabel              matlab.ui.control.Label
        PickfromdifferentblockquntityDropDownLabel  matlab.ui.control.Label
        Pickfromdifferentblockquntity   matlab.ui.control.DropDown
        Copyrights                      matlab.ui.control.Label
        RestoredImage                   matlab.ui.control.UIAxes
        DWTCompressedImage              matlab.ui.control.UIAxes
        Original                        matlab.ui.control.UIAxes
        BlockPermutation                matlab.ui.control.UIAxes
        PixelPermutation                matlab.ui.control.UIAxes
        DecryptedImage                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        InputImage % Description
    end
 
    methods (Access = private)
        
        
        function updateimage(app,imagefile)
            try
                app.InputImage = imresize(im2gray(imread(imagefile)),[512,512]);
            catch ME
                % If problem reading image, display error message
                uialert(app.UIFigure, ME.message, 'Image Error');
                return;          
            end
            apply(app);
        end
        
        function apply(app)
            Haart = dwt2(im2gray(app.InputImage),'haar');
            [n, m]=size(Haart);
            NumberOfBlocks=str2double(app.Pickfromdifferentblockquntity.Value);
            %%%%% create Random keys%%%%%
            Key = randperm(NumberOfBlocks);
            invKey=InverseKey(Key);            
            for k=1:NumberOfBlocks
                Keys{k}=randperm(n*m/NumberOfBlocks);
                invKeys{k}=InverseKey(Keys{k});
            end
            
            BlockPermuteImage = BlockPermute(Haart,NumberOfBlocks,Key);
            
            PixelPermuteImage = PixelPermute(BlockPermuteImage,NumberOfBlocks,Keys);
            
            invPixelPermuteImage = PixelPermute(PixelPermuteImage,NumberOfBlocks,invKeys);
            
            invBlockPermuteImage = BlockPermute(invPixelPermuteImage,NumberOfBlocks,invKey);
            
            iHaart=idwt2(invBlockPermuteImage,[],[],[],'haar');
            
            imshow(uint8(app.InputImage), 'parent', app.Original);
            imshow(uint8(Haart), 'parent', app.DWTCompressedImage);
            imshow(uint8(BlockPermuteImage), 'parent', app.BlockPermutation);
            imshow(uint8(PixelPermuteImage), 'parent', app.PixelPermutation);
            imshow(uint8(invBlockPermuteImage) , 'parent', app.DecryptedImage);
            imshow(uint8(iHaart), 'parent', app.RestoredImage);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.SelectImage.Items = {'MRI 1', 'MRI 2', 'MRI 3', 'MRI 4'};
            app.SelectImage.ItemsData = {'MRI 1.png', 'MRI 2.png', 'MRI 3.png', 'MRI 4.png'};
            updateimage(app,app.SelectImage.Value);
            imshow(app.InputImage,'parent',app.Original); 
            
        end

        % Value changed function: SelectImage
        function SelectImageValueChanged(app, event)
            
            updateimage(app, app.SelectImage.Value);
            
        end

        % Button pushed function: LoadCustomImageButton
        function LoadCustomImageButtonPushed(app, event)
                           
            % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [f, p] = uigetfile(filterspec);
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
               fname = [p f];
               updateimage(app, fname);
            end
            
        end

        % Value changed function: Pickfromdifferentblockquntity
        function PickfromdifferentblockquntityValueChanged(app, event)
           apply(app);            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DSPApp = uifigure('Visible', 'off');
            app.DSPApp.Name = 'DWT Based Encryption Technique For Medical Images App';
            app.GridLayout = uigridlayout(app.DSPApp);
            app.Title = uilabel(app.GridLayout);
            app.Title.Text = 'DWT Based Encryption Technique For Medical Images';
            app.OriginalImageLabel = uilabel(app.GridLayout);
            app.OriginalImageLabel.Text = 'Original Image';
            app.BlockPermutationLabel = uilabel(app.GridLayout);
            app.BlockPermutationLabel.Text = 'Block Permutation';
            app.PixelPermutationLabel = uilabel(app.GridLayout);
            app.PixelPermutationLabel.Text = 'Pixel Permutation';
            app.DecryptedImageLabel = uilabel(app.GridLayout);
            app.DecryptedImageLabel.Text = 'Decrypted Image';
            app.SelectfromPredefinedImageLabel = uilabel(app.GridLayout);
            app.SelectfromPredefinedImageLabel.Text = 'Select from Predefined Image:';
            app.SelectImage = uidropdown(app.GridLayout);
            app.SelectImage.Items = {'MRI 1', 'MRI 2', 'MRI 3'};
            app.SelectImage.ItemsData = {'MRI 1.png', 'MRI 2.png', 'MRI 3.png', 'MRI 4.png'};
            app.SelectImage.ValueChangedFcn = createCallbackFcn(app, @SelectImageValueChanged, true);
            app.SelectImage.Value = 'MRI 1.png';
            app.LoadCustomImageButton = uibutton(app.GridLayout, 'push');
            app.LoadCustomImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadCustomImageButtonPushed, true);
            app.LoadCustomImageButton.Text = 'Load Custom Image ';
            app.DWTCompressedImageLabel = uilabel(app.GridLayout);
            app.DWTCompressedImageLabel.Text = 'DWT Compressed Image';
            app.RestoredImageLabel = uilabel(app.GridLayout);
            app.RestoredImageLabel.Text = 'Restored Image';
            app.PickfromdifferentblockquntityDropDownLabel = uilabel(app.GridLayout);
            app.PickfromdifferentblockquntityDropDownLabel.Text = 'Pick different quantity of blocks:';
            app.Pickfromdifferentblockquntity = uidropdown(app.GridLayout);
            app.Pickfromdifferentblockquntity.Items = {'4', '16', '64'};
            app.Pickfromdifferentblockquntity.ItemsData = {'4', '16', '64'};
            app.Pickfromdifferentblockquntity.ValueChangedFcn = createCallbackFcn(app, @PickfromdifferentblockquntityValueChanged, true);
            app.Pickfromdifferentblockquntity.Value = '16';
            app.Copyrights = uilabel(app.GridLayout);
            app.Copyrights.Text = 'Shay Karadi & Tamir Karamani © ';
            app.RestoredImage = uiaxes(app.GridLayout);
            app.DWTCompressedImage = uiaxes(app.GridLayout);
            app.Original = uiaxes(app.GridLayout);
            app.BlockPermutation = uiaxes(app.GridLayout);
            app.PixelPermutation = uiaxes(app.GridLayout);
            app.DecryptedImage = uiaxes(app.GridLayout);
            % Show the figure after all components are created
            app.DSPApp.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DSP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DSPApp)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DSPApp)
        end
    end
end